classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        WedgesEditFieldLabel       matlab.ui.control.Label
        WedgesEditField            matlab.ui.control.EditField
        ConstraintsEditFieldLabel  matlab.ui.control.Label
        ConstraintsEditField       matlab.ui.control.EditField
        StrategyEditFieldLabel     matlab.ui.control.Label
        StrategyEditField          matlab.ui.control.EditField
        LoadWedgesButton           matlab.ui.control.Button
        LoadConstraintsButton      matlab.ui.control.Button
        LoadStrategyButton         matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        LampWedges                 matlab.ui.control.Lamp
        Lamp_3Label                matlab.ui.control.Label
        LampConstraints            matlab.ui.control.Lamp
        Lamp_2Label                matlab.ui.control.Label
        LampStrategy               matlab.ui.control.Lamp
    end


    properties (Access = private)
        WedgesData      % This property will hold processed data.
        ConstraintsData % This property will hold processed data.
        StrategyData    % This property will hold processed data.
    end

    methods (Access = private)
    
        function WedgesData = readWedges(app, fileID)
            % This fucntion's purpose is to read in and process
            % the wedges file and outputs the data as a matrix.
            % In the matrix M(1,1) would correspond to the label of the first wedge
            % M(2,2) would correspind to the value of the second wedge. Etc.
            
            %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
             
        end
        
        
        function ConstraintsData = readConstraints(app, fileID)
            %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
        end
        
        
        function StrategyData = readStrategy(app, fileID)
            %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~  
        end
    end

    methods (Access = private)

        % Button pushed function: LoadWedgesButton
        function LoadWedgesButtonPushed(app, event)
            % This callback acts when a button is pressed,
            % all it does is open the the file using fopen(),
            % then it will provide feedback via the specified lamp.
            
            
            fileName = app.WedgesEditField.Value;
            
            try % Try to open the file
                
                fileID = fopen(fileName);
                % Check if fileName is a valid address.
                if fileID == -1
                    % Change the lamp color to red.
                    app.LampWedges.Color = [1 0 0];
                else 
%                     climatewedges.WedgesData = readWedges(fileID);
                    % Change the lamp color to green.
                    app.LampWedges.Color = [0 1 0];
                end
                
            catch % The file could not be opened
                
                app.LampWedges.Color = [1 0 0];
                disp('Please enter a valid file address.');
                
            end
        end

        % Button pushed function: LoadConstraintsButton
        function LoadConstraintsButtonPushed(app, event)
            % This callback acts when a button is pressed,
            % all it does is open the the file using fopen(),
            % then it will provide feedback via the specified lamp.
            
            
            fileName = app.ConstraintsEditField.Value;
            try
                fileID = fopen(fileName);
                if fileID == -1
                    app.LampConstraints.Color = [1 0 0];
                else  
%                     climatewedges.ConstraintsData = readConstraints(fileID);
                    app.LampConstraints.Color = [0 1 0];
                end
            catch
                app.LampConstraints.Color = [1 0 0];
                disp('Please enter a valid file address.');
            end
        end

        % Button pushed function: LoadStrategyButton
        function LoadStrategyButtonPushed(app, event)
            % This callback acts when a button is pressed,
            % all it does is open the the file using fopen(),
            % then it will provide feedback via the specified lamp.
            
            
            fileName = app.StrategyEditField.Value;
            try
                fileID = fopen(fileName);
                if fileID == -1
                    app.LampStrategy.Color = [1 0 0];
                else
%                     climatewedges.StrategyData = readStrategy(fileID);
                    app.LampStrategy.Color = [0 1 0];
                end
            catch
                app.LampStrategy.Color = [1 0 0];
                disp('Please enter a valid file address.');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WedgesEditFieldLabel = uilabel(app.UIFigure);
            app.WedgesEditFieldLabel.Text = 'Wedges';
            app.WedgesEditField = uieditfield(app.UIFigure, 'text');
            app.WedgesEditField.Value = 'File Address';
            app.ConstraintsEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintsEditFieldLabel.Text = 'Constraints';
            app.ConstraintsEditField = uieditfield(app.UIFigure, 'text');
            app.ConstraintsEditField.Value = 'File Address';
            app.StrategyEditFieldLabel = uilabel(app.UIFigure);
            app.StrategyEditFieldLabel.Text = 'Strategy';
            app.StrategyEditField = uieditfield(app.UIFigure, 'text');
            app.StrategyEditField.Value = 'File Address';
            app.LoadWedgesButton = uibutton(app.UIFigure, 'push');
            app.LoadWedgesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWedgesButtonPushed, true);
            app.LoadWedgesButton.Text = 'Load Wedges';
            app.LoadConstraintsButton = uibutton(app.UIFigure, 'push');
            app.LoadConstraintsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConstraintsButtonPushed, true);
            app.LoadConstraintsButton.Text = 'Load Constraints';
            app.LoadStrategyButton = uibutton(app.UIFigure, 'push');
            app.LoadStrategyButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStrategyButtonPushed, true);
            app.LoadStrategyButton.Text = 'Load Strategy';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.LampWedges = uilamp(app.UIFigure);
            app.Lamp_3Label = uilabel(app.UIFigure);
            app.Lamp_3Label.Text = '';
            app.LampConstraints = uilamp(app.UIFigure);
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Text = '';
            app.LampStrategy = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end